classdef ActiveContoursApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        ImageMenu                      matlab.ui.container.Menu
        SeperateImageWindowMenu        matlab.ui.container.Menu
        UIAxes                         matlab.ui.control.UIAxes
        CropButton                     matlab.ui.control.Button
        ImageFromlistListBoxLabel      matlab.ui.control.Label
        ImageFromlist                  matlab.ui.control.ListBox
        ChoosefromfileButton           matlab.ui.control.Button
        ImagePreviewLabel              matlab.ui.control.Label
        ImageWindowButton              matlab.ui.control.StateButton
        StartButton                    matlab.ui.control.Button
        HistogramEqualizationCheckBox  matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        MatlabBuiltInTab               matlab.ui.container.Tab
        MethodButtonGroup              matlab.ui.container.ButtonGroup
        RegionButton                   matlab.ui.control.RadioButton
        EdgeButton                     matlab.ui.control.RadioButton
        SmoothFactorEditFieldLabel     matlab.ui.control.Label
        SmoothFactorEditField          matlab.ui.control.NumericEditField
        ContractionBiasEditFieldLabel  matlab.ui.control.Label
        ContractionBiasEditField       matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel    matlab.ui.control.Label
        MaxIterationsEditField         matlab.ui.control.NumericEditField
        LanktonTab                     matlab.ui.container.Tab
        WatershedTab                   matlab.ui.container.Tab
        ToleranceEditFieldLabel        matlab.ui.control.Label
        ToleranceEditField             matlab.ui.control.NumericEditField
        ROIPanel                       matlab.ui.container.Panel
        AddManuallyButton              matlab.ui.control.Button
        AutoButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ShrinkButton                   matlab.ui.control.Button
        ProgressBarAxes                matlab.ui.control.UIAxes
        ProgressBarText                matlab.ui.control.Label
        ResolutionLabel                matlab.ui.control.Label
        ResolutionSlider               matlab.ui.control.Slider
        ResolutionEditField            matlab.ui.control.NumericEditField
        MaskCoverPercentageEditFieldLabel  matlab.ui.control.Label
        MaskCoverPercentageEditField   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Paths ; % Class of all possible paths and images
        SegmentAlgo ; % Class to manage the parameters and execution of the Segmentation Algorithm 
        AnimationControl = struct('NumIterationsPerUpdate' , 2);
        ImagesManager ;  % Class to controls the Behaviour, Manipulations, Additions and Acquisition  of Images
        WindowsManager ; % Manges and controls all open apps and windows
        ApearanceDefaultVals = struct('DefaultButtonColor' , [0.96, 0.96,0.96] , ...
                                      'ActiveButtonColor'  , [0.5 , 0.5 ,0   ] , ...
                                      'WaitingButtonColor' , [0.2 , 0.6 , 1  ] ,...
                                      'MaskColor' , [0,1,0]);
    end
    
    properties (Access = public)
        EmbeddedProgressBar
        is_busy_evolving = false;
    end
    
    methods (Access = private)
        
        function [] = read_user_chosen_image(app)
            chosen_image_str = app.ImageFromlist.Value;
            chosen_image_path = app.Paths.AvailablePictures.(chosen_image_str);
            Im = imread(chosen_image_path);
            app.ImagesManager.set_original_image(Im);           
        end % read_user_chosen_image
        

        function [] = assert_correct_app_folder(app , currentDir)
            is_exist_folder         = [ false        ,  false           ,  false                ];
            folder_that_should_exist= [ "class_defs" , "app_functions"  ,"algorithm_functions"  ];
            
            listing = dir(currentDir);
            for i = 1:length(listing)
                fileOrFodlder = listing(i);
                index_of_found_folder = find(folder_that_should_exist==fileOrFodlder.name);
                %if current folder is not somwthing we're looknig for:
                if isempty(index_of_found_folder)
                    % do nothing
                %else:  it is a necessary folder
                else
                    % we should note that we've found it:
                    is_exist_folder(index_of_found_folder) = true; 
                    % and we should have it in Matlab's paths:
                    addpath(genpath( fullfile(fileOrFodlder.folder,fileOrFodlder.name)  )) 
                end
                
            end
            
            if ~all(is_exist_folder)
                app.wrong_folder_msg(folder_that_should_exist)            
            end
        end
        
        function [] = wrong_folder_msg(app , folder_that_should_exist)
            ErrorMsg= sprintf("%s\n    - %s\n    - %s\n    - %s" , ...
                    "App Loading Error: App should run on its main folder with subfolders:  ", ...
                    folder_that_should_exist(1), ...
                    folder_that_should_exist(2), ...
                    folder_that_should_exist(3)    );
            closeFunc = @(h,e)  delete(app);
            uialert(app.MainWindow,ErrorMsg,"Wrong Loading Path" , 'CloseFcn', closeFunc );
            error(ErrorMsg);   
        end
        
        function [] = set_default_values(app)
            %Available Algorithms and Images:
            app.ImageFromlist.Items = app.Paths.AvailablePictures_string_cell_array();
            %Default Parameters:
            Params = default_params();
            
            % Tabs Group:
            switch Params.General.ChosenAlgorithm
                case AvailableAlgorithms.MatlabBuiltIn
                    app.TabGroup.SelectedTab = app.MatlabBuiltInTab;
                case AvailableAlgorithms.Lankton
                    app.TabGroup.SelectedTab = app.LanktonTab;
                case AvailableAlgorithms.WatershedTab
                    app.TabGroup.SelectedTab = app.WatershedTab;
                otherwise
                    error("Unkown Algorithm");
            end
            
            %% Matlab build in Algo:
            %method:
            switch Params.MatlabBuiltIn.Method
                case AvailableAlgorithms.MatlabBuiltInMethods.Edge
                    app.MethodButtonGroup.SelectedObject = app.EdgeButton;
                case AvailableAlgorithms.MatlabBuiltInMethods.Region
                    app.MethodButtonGroup.SelectedObject = app.RegionButton;
                otherwise
                    error("Unknown Method"); 
            end          
            %Contraction Bias:
            app.ContractionBiasEditField.Value = Params.MatlabBuiltIn.ContractionBias;
            %Smooth Factor:
            app.SmoothFactorEditField.Value = Params.MatlabBuiltIn.SmoothFactor;
            %Max iterations:
            app.MaxIterationsEditField.Value = Params.MatlabBuiltIn.MaxNumIteration;
            
            %% Watershed algo
            %tolerance:
            app.ToleranceEditField.Value = Params.WaterShed.Tolerance;
            
        end % set_default_values
        
    end %private
    
    
    methods (Access = public) 
        
        function [] = show_image(app , Im )
            %Update figures:
            imshow( Im , 'Parent' , app.UIAxes);
            app.UIAxes.XLabel.Visible = "off";
            app.UIAxes.YLabel.Visible = "off";
            app.UIAxes.Title.Visible  = "off";
        end
        
        
        function [] = set_algoInProgress( app , on_off_str )
            switch lower(string(on_off_str))
                case "on"
                    app.StartButton.Text = "Stop";
                case "off"
                    app.StartButton.Text = "Start";
                otherwise
                    error("Wrong string as input");
            end % switch
        end
    end %public
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App folder:
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                disp(status);
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end           
            % check correct path and add to MATLAB'S paths:
            app.assert_correct_app_folder(currentDir)  
            
            %Continue creating the app's Object/Class properties:
            app.Paths = PathsClass( string(currentDir) ,"Search");
%             app.Paths = PathsClass( "C:\Users\tarama\OneDrive - Intel Corporation\Desktop\לימודים\סמסטר ח'\פרויקט ב\ImageProcessing-AirwayMicroscopy-Code\App" ,"given", "C:\Users\tarama\OneDrive - Intel Corporation\Desktop\לימודים\סמסטר ח'\פרויקט ב\Data");
            app.WindowsManager = WindowsManagerClass(app) ; % Manges and controls all open apps and windows
            app.ImagesManager  = ImagesManagerClass(app.WindowsManager , app.ApearanceDefaultVals ) ;
            app.SegmentAlgo    = SegmentationAlgoClass(app.ImagesManager , app.WindowsManager); 
            app.EmbeddedProgressBar = EmbeddedProgressBar(app);
            
            % Set App default values at loading:
            app.set_default_values();
           
            
            %Take care of images:            
            app.read_user_chosen_image();
        end

        % Value changed function: ImageWindowButton
        function ImageWindowButtonValueChanged(app, event)
            app.WindowsManager.show_image_window();
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            app.WindowsManager.close("MainApp");
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            persistent is_waiting_for_second_press  roi
            if isempty(is_waiting_for_second_press)
                is_waiting_for_second_press = false;
                roi = [];
            end
            
            if is_waiting_for_second_press %Do the actuall Cropping:
                %Button visual as ready:
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
                %Crop:
                app.ImagesManager.crop(roi)
                % clear previous masks (ROI's) from the algo-class:
                app.SegmentAlgo.clear_masks();
                %Reset for next crop attempts:
                is_waiting_for_second_press = false;
                delete(roi);
            else
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
                roi = drawrectangle(app.UIAxes);               
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.WaitingButtonColor;
                is_waiting_for_second_press = true;
            end
            
        end

        % Value changed function: ImageFromlist
        function ImageFromlistValueChanged(app, event)
            app.read_user_chosen_image();
        end

        % Button pushed function: AddManuallyButton
        function AddManuallyButtonPushed(app, event)
            app.AddManuallyButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
            roi = drawrectangle(app.UIAxes); 
            Mask = roi.createMask();
            %save results:
            app.SegmentAlgo.add_mask( Mask );
            delete(roi);
            app.AddManuallyButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
            % Update image to show   with the new roi:
            app.ImagesManager.mask_over_image(Mask);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.SegmentAlgo.set("AlgorithmFunction" , selectedTab.Title)
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodButtonGroup.SelectedObject;
            app.SegmentAlgo.set("MatlabFunctionMethod" , selectedButton.Text)
        end

        % Value changed function: SmoothFactorEditField
        function SmoothFactorEditFieldValueChanged(app, event)
            app.SegmentAlgo.set("SmoothFactor" , app.SmoothFactorEditField.Value)
        end

        % Value changed function: ContractionBiasEditField
        function ContractionBiasEditFieldValueChanged(app, event)
            app.SegmentAlgo.set("ContractionBias" , app.ContractionBiasEditField.Value)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.SegmentAlgo.start_or_stop_algorithm();
        end

        % Button pushed function: ChoosefromfileButton
        function ChoosefromfileButtonPushed(app, event)
            [file,path] = uigetfile( app.Paths.AllDirectories.DataDir + filesep + "*.*" ,...
                        'Select an Image File');        
            if isnumeric(file) && file==0  %user cancled
                return
            else
                Im = imread(fullfile(path,file));
                app.ImagesManager.set_original_image(Im)
            end 
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SegmentAlgo.clear_masks();
            app.ImagesManager.clear_masks();
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
 
            im = app.ImagesManager.GreyImage;
            Params = default_params();
            
            PrepProgressBarValue = 20/100;
            
            % Update progressbar:
            app.WindowsManager.update_progress_bar(PrepProgressBarValue/2);
            
            % find all blobs (mask of everything):
            TotalMask = AdaptiveThreshold(im, Params.AdaptiveThreshold.WindowSize, Params.AdaptiveThreshold.Threshold, Params.AdaptiveThreshold.MeanOrMedian, Params.AdaptiveThreshold.SE, ...
                                              Params.AdaptiveThreshold.LowerThresh, Params.AdaptiveThreshold.UpperThresh);
            
            app.WindowsManager.update_progress_bar(PrepProgressBarValue);
                      
            % split the mask into seperate mask for each blob:
            Mask_cell = seperate_mask(TotalMask);
            N = length(Mask_cell);
            % use those seperate masks to update our objects:
            for i=1:N

                temp_mask = Mask_cell{i};
                
                % save the current blob's mask into the masks cell array:
                app.SegmentAlgo.add_mask(temp_mask);
                
                % update image to show with the new current mask:
                app.ImagesManager.mask_over_image(temp_mask);
                
                % update progressbar:
                progressBarValue = PrepProgressBarValue + (1-PrepProgressBarValue)*i/N;
                app.WindowsManager.update_progress_bar(progressBarValue);
            end
            
            drawnow
            % update progressbar:
            app.WindowsManager.update_progress_bar(0);
            % Update Mask Cover Percentage:
            app.SegmentAlgo.calc_and_show_mask_cover_percentage();
        end

        % Value changed function: HistogramEqualizationCheckBox
        function HistogramEqualizationCheckBoxValueChanged(app, event)
            isCheckedHistEq = app.HistogramEqualizationCheckBox.Value;
            if isCheckedHistEq
                app.ImagesManager.set("histeq_image" , "on");
                app.ImagesManager.set("Image2Show_origin" , "GrayImage");
            else
                app.ImagesManager.set("histeq_image" , "off");
                app.ImagesManager.set("Image2Show_origin" , "OriginalImage");
            end
        end

        % Button pushed function: ShrinkButton
        function ShrinkButtonPushed(app, event)
            TotallMask = app.SegmentAlgo.shrink_masks();
            app.ImagesManager.mask_over_image( TotallMask  , "FromScratch" );
        end

        % Callback function
        function LowerResoltionCheckBoxValueChanged(app, event)
    
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            app.ResolutionEditField.Value; % update Precise Value
            app.ImagesManager.set("Resolution" , value);
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.ResolutionSlider.Value = value; % update ScrollerBar
            app.ImagesManager.set("Resolution" , value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Active Contours App ';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.Menu = uimenu(app.MainWindow);
            app.Menu.Text = '....';
            app.ViewMenu = uimenu(app.MainWindow);
            app.ViewMenu.Text = 'View';
            app.ImageMenu = uimenu(app.ViewMenu);
            app.ImageMenu.Text = 'Image';
            app.SeperateImageWindowMenu = uimenu(app.ViewMenu);
            app.SeperateImageWindowMenu.Text = 'Seperate Image Window';
            app.UIAxes = uiaxes(app.MainWindow);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CropButton = uibutton(app.MainWindow, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ImageFromlistListBoxLabel = uilabel(app.MainWindow);
            app.ImageFromlistListBoxLabel.Text = 'Image (From list)';
            app.ImageFromlist = uilistbox(app.MainWindow);
            app.ImageFromlist.ValueChangedFcn = createCallbackFcn(app, @ImageFromlistValueChanged, true);
            app.ChoosefromfileButton = uibutton(app.MainWindow, 'push');
            app.ChoosefromfileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefromfileButtonPushed, true);
            app.ChoosefromfileButton.Text = 'Choose from file';
            app.ImagePreviewLabel = uilabel(app.MainWindow);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.ImageWindowButton = uibutton(app.MainWindow, 'state');
            app.ImageWindowButton.ValueChangedFcn = createCallbackFcn(app, @ImageWindowButtonValueChanged, true);
            app.ImageWindowButton.Text = 'Image Window';
            app.StartButton = uibutton(app.MainWindow, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.HistogramEqualizationCheckBox = uicheckbox(app.MainWindow);
            app.HistogramEqualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @HistogramEqualizationCheckBoxValueChanged, true);
            app.HistogramEqualizationCheckBox.Text = {'Histogram'; 'Equalization'};
            app.TabGroup = uitabgroup(app.MainWindow);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MatlabBuiltInTab = uitab(app.TabGroup);
            app.MatlabBuiltInTab.Title = 'MatlabBuiltIn';
            app.MethodButtonGroup = uibuttongroup(app.MatlabBuiltInTab);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.RegionButton = uiradiobutton(app.MethodButtonGroup);
            app.RegionButton.Text = 'Region';
            app.RegionButton.Value = true;
            app.EdgeButton = uiradiobutton(app.MethodButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.SmoothFactorEditFieldLabel = uilabel(app.MatlabBuiltInTab);
            app.SmoothFactorEditFieldLabel.Text = 'Smooth Factor';
            app.SmoothFactorEditField = uieditfield(app.MatlabBuiltInTab, 'numeric');
            app.SmoothFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothFactorEditFieldValueChanged, true);
            app.SmoothFactorEditField.Value = 4;
            app.ContractionBiasEditFieldLabel = uilabel(app.MatlabBuiltInTab);
            app.ContractionBiasEditFieldLabel.Text = 'Contraction Bias';
            app.ContractionBiasEditField = uieditfield(app.MatlabBuiltInTab, 'numeric');
            app.ContractionBiasEditField.ValueChangedFcn = createCallbackFcn(app, @ContractionBiasEditFieldValueChanged, true);
            app.ContractionBiasEditField.Value = 0.01;
            app.MaxIterationsEditFieldLabel = uilabel(app.MatlabBuiltInTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.MatlabBuiltInTab, 'numeric');
            app.MaxIterationsEditField.Value = 1000;
            app.LanktonTab = uitab(app.TabGroup);
            app.LanktonTab.Title = 'Lankton';
            app.WatershedTab = uitab(app.TabGroup);
            app.WatershedTab.Title = 'Watershed';
            app.ToleranceEditFieldLabel = uilabel(app.WatershedTab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.WatershedTab, 'numeric');
            app.ToleranceEditField.Limits = [0 100];
            app.ToleranceEditField.Value = 1;
            app.ROIPanel = uipanel(app.MainWindow);
            app.ROIPanel.Title = 'ROI';
            app.AddManuallyButton = uibutton(app.ROIPanel, 'push');
            app.AddManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @AddManuallyButtonPushed, true);
            app.AddManuallyButton.Text = 'Add Manually';
            app.AutoButton = uibutton(app.ROIPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.ResetButton = uibutton(app.ROIPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShrinkButton = uibutton(app.ROIPanel, 'push');
            app.ShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkButtonPushed, true);
            app.ShrinkButton.Text = 'Shrink';
            app.ProgressBarAxes = uiaxes(app.MainWindow);
            title(app.ProgressBarAxes, 'Title')
            xlabel(app.ProgressBarAxes, 'X')
            ylabel(app.ProgressBarAxes, 'Y')
            app.ProgressBarText = uilabel(app.MainWindow);
            app.ResolutionLabel = uilabel(app.MainWindow);
            app.ResolutionLabel.Text = '  Resolution                          [%]';
            app.ResolutionSlider = uislider(app.MainWindow);
            app.ResolutionSlider.MajorTicks = [0 20 40 60 80 100];
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.Value = 100;
            app.ResolutionEditField = uieditfield(app.MainWindow, 'numeric');
            app.ResolutionEditField.Limits = [0 100];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.MaskCoverPercentageEditFieldLabel = uilabel(app.MainWindow);
            app.MaskCoverPercentageEditFieldLabel.Text = 'Mask Cover Percentage';
            app.MaskCoverPercentageEditField = uieditfield(app.MainWindow, 'numeric');
            app.MaskCoverPercentageEditField.Editable = 'off';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActiveContoursApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end